classdef FormularioPrincipal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SISTEMASDEECUACIONESLabel      matlab.ui.control.Label
        TabGroup2                      matlab.ui.container.TabGroup
        LINEALESTab                    matlab.ui.container.Tab
        TabGroup                       matlab.ui.container.TabGroup
        MATRIZINVERSATab               matlab.ui.container.Tab
        btnProbarCalculadora_3         matlab.ui.control.Button
        btnVerEjemplos_3               matlab.ui.control.Button
        MATRIZINVERSALabel             matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        dondeAdjACofacATLabel          matlab.ui.control.Label
        EncontramosprimerolamatrizdecofactoresLabel  matlab.ui.control.Label
        paraahoraobtenerlaadjuntatransponemoslamatrizdecofactoresLabel  matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        EjemploLabel                   matlab.ui.control.Label
        Image3                         matlab.ui.control.Image
        Image4                         matlab.ui.control.Image
        Image5                         matlab.ui.control.Image
        Image6                         matlab.ui.control.Image
        FinalmentelainversadeAesLabel  matlab.ui.control.Label
        Image7                         matlab.ui.control.Image
        Label_9                        matlab.ui.control.Label
        Image8                         matlab.ui.control.Image
        ELIMINACINDEGAUSSTab           matlab.ui.container.Tab
        btnProbarCalculadora_4         matlab.ui.control.Button
        btnVerEjemplos_4               matlab.ui.control.Button
        ELIMINACINDEGAUSSYGAUSSJORDANLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        RequisitosdelosmtodosLabel     matlab.ui.control.Label
        UnamatrizdedimensinnxnLabel    matlab.ui.control.Label
        UnsistemadeecuacionesconunasolucinnicaLabel  matlab.ui.control.Label
        DiferenciasentreunoyotromtodoLabel  matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        TodosloscoeficientessoncerosLabel  matlab.ui.control.Label
        DosfilassonigualesLabel        matlab.ui.control.Label
        UnafilaesproporcionalaotraLabel  matlab.ui.control.Label
        UnafilaescombinacinlinealdeotrasLabel  matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        btnProbarCalculadora_11        matlab.ui.control.Button
        REGLADECRAMERTab               matlab.ui.container.Tab
        btnProbarCalculadora_2         matlab.ui.control.Button
        btnVerEjemplos_2               matlab.ui.control.Button
        Image_2                        matlab.ui.control.Image
        Label_10                       matlab.ui.control.Label
        Image2_2                       matlab.ui.control.Image
        Label_11                       matlab.ui.control.Label
        REGLADECRAMERLabel             matlab.ui.control.Label
        DESCOMPOSICINLUTab             matlab.ui.container.Tab
        btnProbarCalculadora_6         matlab.ui.control.Button
        btnVerEjemplos_6               matlab.ui.control.Button
        FACTORIZACIONLULabel           matlab.ui.control.Label
        Label_12                       matlab.ui.control.Label
        Image9                         matlab.ui.control.Image
        CROUDTab                       matlab.ui.container.Tab
        btnProbarCalculadora_7         matlab.ui.control.Button
        btnVerEjemplos_7               matlab.ui.control.Button
        ttleFACTORIZACIONCROUDLabel    matlab.ui.control.Label
        Label_13                       matlab.ui.control.Label
        Image10                        matlab.ui.control.Image
        CHOLESKYTab                    matlab.ui.container.Tab
        btnProbarCalculadora_8         matlab.ui.control.Button
        btnVerEjemplos_8               matlab.ui.control.Button
        METODODECHOLESKYLabel          matlab.ui.control.Label
        EstemtodoconsisteenfactorizarlamatrizsimtricaALabel  matlab.ui.control.Label
        Image11                        matlab.ui.control.Image
        EstemtodoconsisteenfactorizarlamatrizsimtricaALabel_2  matlab.ui.control.Label
        Image12                        matlab.ui.control.Image
        Image13                        matlab.ui.control.Image
        btnVerEjemplos_14              matlab.ui.control.Button
        AndrLouisCholeskyLabel         matlab.ui.control.Label
        JACOBITab                      matlab.ui.container.Tab
        btnProbarCalculadora_9         matlab.ui.control.Button
        btnVerEjemplos_9               matlab.ui.control.Button
        METODODEJACOBILabel            matlab.ui.control.Label
        Label_26                       matlab.ui.control.Label
        Image14                        matlab.ui.control.Image
        Image14_2                      matlab.ui.control.Image
        Image14_3                      matlab.ui.control.Image
        Label_27                       matlab.ui.control.Label
        Image14_4                      matlab.ui.control.Image
        Image14_5                      matlab.ui.control.Image
        Image14_6                      matlab.ui.control.Image
        Label_28                       matlab.ui.control.Label
        btnVerEjemplos_15              matlab.ui.control.Button
        CarlGustavJakobJacobiLabel     matlab.ui.control.Label
        GAUSSSEIDELTab                 matlab.ui.container.Tab
        btnProbarCalculadora_10        matlab.ui.control.Button
        btnVerEjemplos_10              matlab.ui.control.Button
        GAUSSSEIDELLabel               matlab.ui.control.Label
        Label_14                       matlab.ui.control.Label
        Image9_2                       matlab.ui.control.Image
        Label_15                       matlab.ui.control.Label
        Label_16                       matlab.ui.control.Label
        Image9_3                       matlab.ui.control.Image
        Label_17                       matlab.ui.control.Label
        GAUSSSEIDELCONRELAJACINTab     matlab.ui.container.Tab
        btnProbarCalculadora           matlab.ui.control.Button
        btnVerEjemplos                 matlab.ui.control.Button
        GAUSSSEIDELCONRELAJACINLabel   matlab.ui.control.Label
        Label_18                       matlab.ui.control.Label
        Image9_4                       matlab.ui.control.Image
        Label_19                       matlab.ui.control.Label
        Label_20                       matlab.ui.control.Label
        Image9_5                       matlab.ui.control.Image
        Image9_6                       matlab.ui.control.Image
        Image9_7                       matlab.ui.control.Image
        Label_21                       matlab.ui.control.Label
        Label_22                       matlab.ui.control.Label
        Label_23                       matlab.ui.control.Label
        Label_24                       matlab.ui.control.Label
        Label_25                       matlab.ui.control.Label
        NOLINEALESTab                  matlab.ui.container.Tab
        TabGroup3                      matlab.ui.container.TabGroup
        MTODODENEWTONTab               matlab.ui.container.Tab
        btnProbarCalculadora_13        matlab.ui.control.Button
        btnVerEjemplos_12              matlab.ui.control.Button
        MTODODENEWTONLabel             matlab.ui.control.Label
        Image2_3                       matlab.ui.control.Image
        Label_30                       matlab.ui.control.Label
        Image2_4                       matlab.ui.control.Image
        Label_31                       matlab.ui.control.Label
        Label_32                       matlab.ui.control.Label
        Image2_5                       matlab.ui.control.Image
        Label_33                       matlab.ui.control.Label
        Image2_6                       matlab.ui.control.Image
        Label_34                       matlab.ui.control.Label
        Image2_7                       matlab.ui.control.Image
        Label_35                       matlab.ui.control.Label
        Image2_8                       matlab.ui.control.Image
        Label_36                       matlab.ui.control.Label
        Image2_9                       matlab.ui.control.Image
        ButtonTest                     matlab.ui.control.Button
        ButtonHome                     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: btnProbarCalculadora_3
        function btnProbarCalculadora_3ButtonPushed(app, event)
            MetodoMatrizInversa;
            delete(app);
        end

        % Button pushed function: btnProbarCalculadora_4
        function btnProbarCalculadora_4ButtonPushed(app, event)
            MetodoDeEliminacionDeGauss;
            delete(app);
        end

        % Callback function
        function btnProbarCalculadora_5ButtonPushed(app, event)
            MetodoDeEliminacionDeGaussJordan;
            delete(app);
        end

        % Callback function
        function ButtonPushed(app, event)
            AcercaDe;
            delete(app);
        end

        % Button pushed function: btnProbarCalculadora_11
        function btnProbarCalculadora_11ButtonPushed(app, event)
            MetodoDeEliminacionDeGaussJordan;
            delete(app);
        end

        % Size changed function: REGLADECRAMERTab
        function REGLADECRAMERTabSizeChanged(app, event)
            position = app.REGLADECRAMERTab.Position;
            
        end

        % Button pushed function: btnVerEjemplos_15
        function btnVerEjemplos_15ButtonPushed(app, event)
             web('https://es.wikipedia.org/wiki/Carl_Gustav_Jakob_Jacobi')
        end

        % Button pushed function: btnVerEjemplos_14
        function btnVerEjemplos_14ButtonPushed(app, event)
            web('https://es.wikipedia.org/wiki/Andr%C3%A9-Louis_Cholesky')
        end

        % Button pushed function: btnProbarCalculadora_8
        function btnProbarCalculadora_8ButtonPushed(app, event)
            MetodoCholeski;
            delete(app);
        end

        % Button pushed function: btnProbarCalculadora_9
        function btnProbarCalculadora_9ButtonPushed(app, event)
            MetodoJacobi;
            delete(app);
        end

        % Button pushed function: btnVerEjemplos_9
        function btnVerEjemplos_9ButtonPushed(app, event)
            MetodoJacobiEjemplo;
            delete(app);
        end

        % Callback function
        function btnResolverCuestionario_3ButtonPushed(app, event)
            web('https://www.proprofs.com/quiz-school/ugc/story.php?title=mjk5odqxoq7iq7')
        end

        % Button pushed function: btnVerEjemplos_3
        function btnVerEjemplos_3ButtonPushed(app, event)
            web('https://drive.google.com/drive/folders/18e2kNG3lV5bLwAZ06E9rx35gmqji-eNz?usp=sharing')
        end

        % Button pushed function: btnVerEjemplos_4
        function btnVerEjemplos_4ButtonPushed(app, event)
            web('https://drive.google.com/file/d/1wa4ag1L3gW_72eVpdZ3RrSQyeRQqtjfC/view?usp=sharing')
        end

        % Button pushed function: btnProbarCalculadora
        function btnProbarCalculadoraButtonPushed(app, event)
            MetodoGaussSeidelRelajacion;
            delete(app);
        end

        % Button pushed function: btnProbarCalculadora_10
        function btnProbarCalculadora_10ButtonPushed(app, event)
            MetodoGaussSeidel;
            delete(app);
        end

        % Button pushed function: btnProbarCalculadora_2
        function btnProbarCalculadora_2ButtonPushed(app, event)
            MetodoCramer;
            delete(app);
        end

        % Button pushed function: btnProbarCalculadora_6
        function btnProbarCalculadora_6ButtonPushed(app, event)
            MetodoFactorizacionLU;
            delete(app);
        end

        % Button pushed function: btnProbarCalculadora_7
        function btnProbarCalculadora_7ButtonPushed(app, event)
            MetodoFactorizacionCROUD;
            delete(app);
        end

        % Button pushed function: ButtonTest
        function ButtonTestPushed2(app, event)
            web('https://www.proprofs.com/quiz-school/ugc/story.php?title=evaluacin-sistemas-de-ecuaciones')
        end

        % Button pushed function: ButtonHome
        function ButtonHomePushed(app, event)
            Home;
            delete(app);
        end

        % Button pushed function: btnVerEjemplos_2
        function btnVerEjemplos_2ButtonPushed(app, event)
            web('https://drive.google.com/file/d/1aNwHZU8sUy7L_wVrV8TEYLaXU3vz0PQ3/view?usp=sharing')
        end

        % Button pushed function: btnVerEjemplos_6
        function btnVerEjemplos_6ButtonPushed(app, event)
            web('https://drive.google.com/file/d/1QgAcwVtSZ_PS6ozZ03rqQDj73HgkQqP5/view?usp=sharing')
        end

        % Button pushed function: btnVerEjemplos_7
        function btnVerEjemplos_7ButtonPushed(app, event)
            web('https://drive.google.com/file/d/1Vlj29x5RxoNI_5B-IBl-Rbol4vCmCjYd/view')
        end

        % Button pushed function: btnVerEjemplos
        function btnVerEjemplosButtonPushed(app, event)
            web('https://drive.google.com/file/d/1lJq5zKNPRkJ57pFXz5k7BYWNUn93PbqV/view?usp=sharing')
        end

        % Button pushed function: btnVerEjemplos_10
        function btnVerEjemplos_10ButtonPushed(app, event)
            web('https://drive.google.com/drive/folders/166hin4gqv_C6CG6zlc8ad6-6gaxz1974?usp=sharing')
        end

        % Button pushed function: btnProbarCalculadora_13
        function btnProbarCalculadora_13ButtonPushed(app, event)
            MetodoNewtonRaphson;
            delete(app);
        end

        % Button pushed function: btnVerEjemplos_12
        function btnVerEjemplos_12ButtonPushed(app, event)
            web('https://drive.google.com/drive/folders/1laQJ8NHk1jaAhTSuPEErDeDXU9Z8cooh?usp=sharing')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SISTEMASDEECUACIONESLabel = uilabel(app.UIFigure);
            app.SISTEMASDEECUACIONESLabel.Text = 'SISTEMAS DE ECUACIONES';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.LINEALESTab = uitab(app.TabGroup2);
            app.LINEALESTab.Title = 'LINEALES';
            app.TabGroup = uitabgroup(app.LINEALESTab);
            app.MATRIZINVERSATab = uitab(app.TabGroup);
            app.MATRIZINVERSATab.Title = 'MATRIZ INVERSA';
            app.btnProbarCalculadora_3 = uibutton(app.MATRIZINVERSATab, 'push');
            app.btnProbarCalculadora_3.ButtonPushedFcn = createCallbackFcn(app, @btnProbarCalculadora_3ButtonPushed, true);
            app.btnProbarCalculadora_3.Text = 'Probar Calculadora';
            app.btnVerEjemplos_3 = uibutton(app.MATRIZINVERSATab, 'push');
            app.btnVerEjemplos_3.ButtonPushedFcn = createCallbackFcn(app, @btnVerEjemplos_3ButtonPushed, true);
            app.btnVerEjemplos_3.Text = 'Ver Ejemplos';
            app.MATRIZINVERSALabel = uilabel(app.MATRIZINVERSATab);
            app.MATRIZINVERSALabel.Text = 'MATRIZ INVERSA';
            app.Label_8 = uilabel(app.MATRIZINVERSATab);
            app.Label_8.Text = 'La inversa de una matriz A, denominada A^(-1), calculada mediante cofactores:';
            app.dondeAdjACofacATLabel = uilabel(app.MATRIZINVERSATab);
            app.dondeAdjACofacATLabel.Text = 'donde Adj(A) = [Cofac(A)]^T';
            app.EncontramosprimerolamatrizdecofactoresLabel = uilabel(app.MATRIZINVERSATab);
            app.EncontramosprimerolamatrizdecofactoresLabel.Text = 'Encontramos primero la matriz de cofactores:';
            app.paraahoraobtenerlaadjuntatransponemoslamatrizdecofactoresLabel = uilabel(app.MATRIZINVERSATab);
            app.paraahoraobtenerlaadjuntatransponemoslamatrizdecofactoresLabel.Text = 'para ahora obtener la adjunta, transponemos la matriz de cofactores:';
            app.Image2 = uiimage(app.MATRIZINVERSATab);
            app.Image2.ImageSource = 'matriz_inversa.png';
            app.EjemploLabel = uilabel(app.MATRIZINVERSATab);
            app.EjemploLabel.Text = 'Ejemplo';
            app.Image3 = uiimage(app.MATRIZINVERSATab);
            app.Image3.ImageSource = 'matriz_inversa1.png';
            app.Image4 = uiimage(app.MATRIZINVERSATab);
            app.Image4.ImageSource = 'matriz_inversa2.png';
            app.Image5 = uiimage(app.MATRIZINVERSATab);
            app.Image5.ImageSource = 'matriz_inversa3.png';
            app.Image6 = uiimage(app.MATRIZINVERSATab);
            app.Image6.ImageSource = 'matriz_inversa4.png';
            app.FinalmentelainversadeAesLabel = uilabel(app.MATRIZINVERSATab);
            app.FinalmentelainversadeAesLabel.Text = 'Finalmente, la inversa de A es:';
            app.Image7 = uiimage(app.MATRIZINVERSATab);
            app.Image7.ImageSource = 'matriz_inversa5.png';
            app.Label_9 = uilabel(app.MATRIZINVERSATab);
            app.Label_9.Text = 'Para comprobar los resultados, podemos realizar la pre o posmultiplicación de A por A^(-1)';
            app.Image8 = uiimage(app.MATRIZINVERSATab);
            app.Image8.ImageSource = 'matriz_inversa6.png';
            app.ELIMINACINDEGAUSSTab = uitab(app.TabGroup);
            app.ELIMINACINDEGAUSSTab.Title = 'ELIMINACIÓN DE GAUSS';
            app.btnProbarCalculadora_4 = uibutton(app.ELIMINACINDEGAUSSTab, 'push');
            app.btnProbarCalculadora_4.ButtonPushedFcn = createCallbackFcn(app, @btnProbarCalculadora_4ButtonPushed, true);
            app.btnProbarCalculadora_4.Text = 'Calculadora Gauss';
            app.btnVerEjemplos_4 = uibutton(app.ELIMINACINDEGAUSSTab, 'push');
            app.btnVerEjemplos_4.ButtonPushedFcn = createCallbackFcn(app, @btnVerEjemplos_4ButtonPushed, true);
            app.btnVerEjemplos_4.Text = 'Ver Ejemplos';
            app.ELIMINACINDEGAUSSYGAUSSJORDANLabel = uilabel(app.ELIMINACINDEGAUSSTab);
            app.ELIMINACINDEGAUSSYGAUSSJORDANLabel.Text = 'ELIMINACIÓN DE GAUSS Y GAUSS-JORDAN';
            app.Label = uilabel(app.ELIMINACINDEGAUSSTab);
            app.Label.Text = 'Los métodos de Gauss y Gauss-Jordan son ambos utilizados para encontrar la solución a un sistema de ecuaciones.';
            app.RequisitosdelosmtodosLabel = uilabel(app.ELIMINACINDEGAUSSTab);
            app.RequisitosdelosmtodosLabel.Text = 'Requisitos de los métodos:';
            app.UnamatrizdedimensinnxnLabel = uilabel(app.ELIMINACINDEGAUSSTab);
            app.UnamatrizdedimensinnxnLabel.Text = '1. Una matriz de dimensión n x n.';
            app.UnsistemadeecuacionesconunasolucinnicaLabel = uilabel(app.ELIMINACINDEGAUSSTab);
            app.UnsistemadeecuacionesconunasolucinnicaLabel.Text = '2. Un sistema de ecuaciones con una solución única.';
            app.DiferenciasentreunoyotromtodoLabel = uilabel(app.ELIMINACINDEGAUSSTab);
            app.DiferenciasentreunoyotromtodoLabel.Text = 'Diferencias entre uno y otro método:';
            app.Label_2 = uilabel(app.ELIMINACINDEGAUSSTab);
            app.Label_2.Text = 'La eliminación de Gauss consiste en transformar un sistema de ecuaciones en otro equivalente de forma que éste sea escalonado.';
            app.Label_3 = uilabel(app.ELIMINACINDEGAUSSTab);
            app.Label_3.Text = 'Obtenemos sistemas equivalentes por eliminación de ecuaciones dependientes, si:';
            app.TodosloscoeficientessoncerosLabel = uilabel(app.ELIMINACINDEGAUSSTab);
            app.TodosloscoeficientessoncerosLabel.Text = '1. Todos los coeficientes son ceros.';
            app.DosfilassonigualesLabel = uilabel(app.ELIMINACINDEGAUSSTab);
            app.DosfilassonigualesLabel.Text = '2. Dos filas son iguales.';
            app.UnafilaesproporcionalaotraLabel = uilabel(app.ELIMINACINDEGAUSSTab);
            app.UnafilaesproporcionalaotraLabel.Text = '3. Una fila es proporcional a otra.';
            app.UnafilaescombinacinlinealdeotrasLabel = uilabel(app.ELIMINACINDEGAUSSTab);
            app.UnafilaescombinacinlinealdeotrasLabel.Text = '4. Una fila es combinación lineal de otras.';
            app.Label_4 = uilabel(app.ELIMINACINDEGAUSSTab);
            app.Label_4.Text = 'El método de Gauss transforma la matriz de coeficientes en una matriz triangular superior. Mientras que el método de';
            app.Label_5 = uilabel(app.ELIMINACINDEGAUSSTab);
            app.Label_5.Text = 'Gauss-Jordan continúa el proceso de transformación hasta obtener una matriz diagonal.';
            app.Label_6 = uilabel(app.ELIMINACINDEGAUSSTab);
            app.Label_6.Text = 'La principal diferencia entre los métodos es que Gauss finaliza al obtener un sistema equivalente en forma escalonada';
            app.Label_7 = uilabel(app.ELIMINACINDEGAUSSTab);
            app.Label_7.Text = 'mientras Gauss-Jordan finaliza al obtener un sistema equivalente en forma escalonada reducida.';
            app.btnProbarCalculadora_11 = uibutton(app.ELIMINACINDEGAUSSTab, 'push');
            app.btnProbarCalculadora_11.ButtonPushedFcn = createCallbackFcn(app, @btnProbarCalculadora_11ButtonPushed, true);
            app.btnProbarCalculadora_11.Text = 'Calculadora Gauss-Jordan';
            app.REGLADECRAMERTab = uitab(app.TabGroup);
            app.REGLADECRAMERTab.SizeChangedFcn = createCallbackFcn(app, @REGLADECRAMERTabSizeChanged, true);
            app.REGLADECRAMERTab.Title = 'REGLA DE CRAMER';
            app.btnProbarCalculadora_2 = uibutton(app.REGLADECRAMERTab, 'push');
            app.btnProbarCalculadora_2.ButtonPushedFcn = createCallbackFcn(app, @btnProbarCalculadora_2ButtonPushed, true);
            app.btnProbarCalculadora_2.Text = 'Probar Calculadora';
            app.btnVerEjemplos_2 = uibutton(app.REGLADECRAMERTab, 'push');
            app.btnVerEjemplos_2.ButtonPushedFcn = createCallbackFcn(app, @btnVerEjemplos_2ButtonPushed, true);
            app.btnVerEjemplos_2.Text = 'Ver Ejemplos';
            app.Image_2 = uiimage(app.REGLADECRAMERTab);
            app.Image_2.ImageSource = 'imgCramer1.jpg';
            app.Label_10 = uilabel(app.REGLADECRAMERTab);
            app.Label_10.Text = {'Donde '; 'A -> es la matriz de coeficientes, '; 'X -> es la matriz columna con las incógnitas y '; 'b -> es la matriz columna con los términos independientes.'; 'Bajo estas condiciones, la regla de Cramer es la siguiente:'; 'La incógnita Xi del sistema AX=b es '};
            app.Image2_2 = uiimage(app.REGLADECRAMERTab);
            app.Image2_2.ImageSource = 'imgCramer2.jpg';
            app.Label_11 = uilabel(app.REGLADECRAMERTab);
            app.Label_11.Text = {'Donde Ai es la matriz A, pero cambiando la columna i de A por la columna de términos independientes, b.'; 'Es más fácil entender la regla viendo los ejemplos.'; ''};
            app.REGLADECRAMERLabel = uilabel(app.REGLADECRAMERTab);
            app.REGLADECRAMERLabel.Text = 'REGLA DE CRAMER';
            app.DESCOMPOSICINLUTab = uitab(app.TabGroup);
            app.DESCOMPOSICINLUTab.Title = 'DESCOMPOSICIÓN LU';
            app.btnProbarCalculadora_6 = uibutton(app.DESCOMPOSICINLUTab, 'push');
            app.btnProbarCalculadora_6.ButtonPushedFcn = createCallbackFcn(app, @btnProbarCalculadora_6ButtonPushed, true);
            app.btnProbarCalculadora_6.Text = 'Probar Calculadora';
            app.btnVerEjemplos_6 = uibutton(app.DESCOMPOSICINLUTab, 'push');
            app.btnVerEjemplos_6.ButtonPushedFcn = createCallbackFcn(app, @btnVerEjemplos_6ButtonPushed, true);
            app.btnVerEjemplos_6.Text = 'Ver Ejemplos';
            app.FACTORIZACIONLULabel = uilabel(app.DESCOMPOSICINLUTab);
            app.FACTORIZACIONLULabel.Text = 'FACTORIZACION LU';
            app.Label_12 = uilabel(app.DESCOMPOSICINLUTab);
            app.Label_12.Text = {'Si se tiene la factorizacion o descomposicion:'; '		'; '			A = LU'; ''; 'Entonces, resolver Ax=b si y solo si LUx=b se convierte en resolver:'; 'Ly=b -> Resolver y por sustitucion hacia adelante'; 'Ux=y -> Resoolver x por sustitucion hacia atras'; ''; 'TEOREMA'; 'La condicion necesaria y suficiente para que una matriz regular A se pueda descomponer como A=LU es que todos'; 'los menores principales tengan un determinante no nulo (diferente de 0).'};
            app.Image9 = uiimage(app.DESCOMPOSICINLUTab);
            app.Image9.ImageSource = 'imgLU1.jpg';
            app.CROUDTab = uitab(app.TabGroup);
            app.CROUDTab.Title = 'CROUD';
            app.btnProbarCalculadora_7 = uibutton(app.CROUDTab, 'push');
            app.btnProbarCalculadora_7.ButtonPushedFcn = createCallbackFcn(app, @btnProbarCalculadora_7ButtonPushed, true);
            app.btnProbarCalculadora_7.Text = 'Probar Calculadora';
            app.btnVerEjemplos_7 = uibutton(app.CROUDTab, 'push');
            app.btnVerEjemplos_7.ButtonPushedFcn = createCallbackFcn(app, @btnVerEjemplos_7ButtonPushed, true);
            app.btnVerEjemplos_7.Text = 'Ver Ejemplos';
            app.ttleFACTORIZACIONCROUDLabel = uilabel(app.CROUDTab);
            app.ttleFACTORIZACIONCROUDLabel.Text = 'FACTORIZACION CROUD';
            app.Label_13 = uilabel(app.CROUDTab);
            app.Label_13.Text = {'El metodo de CROUD genera una matriz triangular inferior y una matriz triangular superior unidad.'; 'La descomposicion de la matriz A es:'; ''; '					A=LU'; ''; 'Una matriz de coeficientes se puede descomponer en una matriz superior L y una inferior U. Se presenta de la'; 'siguiente forma'};
            app.Image10 = uiimage(app.CROUDTab);
            app.Image10.ImageSource = 'imgCROUD1.jpg';
            app.CHOLESKYTab = uitab(app.TabGroup);
            app.CHOLESKYTab.Title = 'CHOLESKY';
            app.btnProbarCalculadora_8 = uibutton(app.CHOLESKYTab, 'push');
            app.btnProbarCalculadora_8.ButtonPushedFcn = createCallbackFcn(app, @btnProbarCalculadora_8ButtonPushed, true);
            app.btnProbarCalculadora_8.Text = 'Probar Calculadora';
            app.btnVerEjemplos_8 = uibutton(app.CHOLESKYTab, 'push');
            app.btnVerEjemplos_8.Text = 'Ver Ejemplos';
            app.METODODECHOLESKYLabel = uilabel(app.CHOLESKYTab);
            app.METODODECHOLESKYLabel.Text = 'METODO DE CHOLESKY';
            app.EstemtodoconsisteenfactorizarlamatrizsimtricaALabel = uilabel(app.CHOLESKYTab);
            app.EstemtodoconsisteenfactorizarlamatrizsimtricaALabel.Text = {'Este método consiste en factorizar la matriz simétrica A en una matrz L multiplicada por su transpuesta'; ''};
            app.Image11 = uiimage(app.CHOLESKYTab);
            app.Image11.ImageSource = 'choleski1.png';
            app.EstemtodoconsisteenfactorizarlamatrizsimtricaALabel_2 = uilabel(app.CHOLESKYTab);
            app.EstemtodoconsisteenfactorizarlamatrizsimtricaALabel_2.Text = {'De multiplicar e igualar entre sí los términos de la ecuación (tal como lo hicimos en el método de '; 'Crout) se obtienen las relaciones recurrentes. Para hallar los valores de L'; 'nos apoyamos de la siguientes formulas.'; ''};
            app.Image12 = uiimage(app.CHOLESKYTab);
            app.Image12.ImageSource = 'choleski2.png';
            app.Image13 = uiimage(app.CHOLESKYTab);
            app.Image13.ImageSource = 'choleski3.png';
            app.btnVerEjemplos_14 = uibutton(app.CHOLESKYTab, 'push');
            app.btnVerEjemplos_14.ButtonPushedFcn = createCallbackFcn(app, @btnVerEjemplos_14ButtonPushed, true);
            app.btnVerEjemplos_14.Icon = 'choleski4.jpg';
            app.btnVerEjemplos_14.Text = '';
            app.AndrLouisCholeskyLabel = uilabel(app.CHOLESKYTab);
            app.AndrLouisCholeskyLabel.Text = 'André-Louis Cholesky';
            app.JACOBITab = uitab(app.TabGroup);
            app.JACOBITab.Title = 'JACOBI';
            app.btnProbarCalculadora_9 = uibutton(app.JACOBITab, 'push');
            app.btnProbarCalculadora_9.ButtonPushedFcn = createCallbackFcn(app, @btnProbarCalculadora_9ButtonPushed, true);
            app.btnProbarCalculadora_9.Text = 'Probar Calculadora';
            app.btnVerEjemplos_9 = uibutton(app.JACOBITab, 'push');
            app.btnVerEjemplos_9.ButtonPushedFcn = createCallbackFcn(app, @btnVerEjemplos_9ButtonPushed, true);
            app.btnVerEjemplos_9.Text = 'Ver Ejemplos';
            app.METODODEJACOBILabel = uilabel(app.JACOBITab);
            app.METODODEJACOBILabel.Text = 'METODO DE JACOBI';
            app.Label_26 = uilabel(app.JACOBITab);
            app.Label_26.Text = {'Este método se puede ilustrar usando las siguientes ecuaciones'; ''};
            app.Image14 = uiimage(app.JACOBITab);
            app.Image14.ImageSource = 'jacobi1.png';
            app.Image14_2 = uiimage(app.JACOBITab);
            app.Image14_2.ImageSource = 'jacobi2.png';
            app.Image14_3 = uiimage(app.JACOBITab);
            app.Image14_3.ImageSource = 'jacobi3.png';
            app.Label_27 = uilabel(app.JACOBITab);
            app.Label_27.Text = {'El método comienza resolviendo la ec. 1 para x1, x2 y x3 e introduciendo el índice k '; 'que se utilizara para indicar el número de iteraciones, se obtiene:'; ''};
            app.Image14_4 = uiimage(app.JACOBITab);
            app.Image14_4.ImageSource = 'jacobi4.png';
            app.Image14_5 = uiimage(app.JACOBITab);
            app.Image14_5.ImageSource = 'jacobi5.png';
            app.Image14_6 = uiimage(app.JACOBITab);
            app.Image14_6.ImageSource = 'jacobi6.png';
            app.Label_28 = uilabel(app.JACOBITab);
            app.Label_28.Text = {'Para resolver por jacobi se requiere un vector incial'; 'xi = (x1 (k),  x2 (k), x3 (k))'; 'en caso de no haberlo suponemos que los valores inciales seran 0'; 'x0 = (0 (0),  0 (0), 0 (0))'; 'Tambien se necesita indicar el erro y el numoer de iteraciones'; 'El proceso se continua hasta que | xi+1 – xi| ≤ error'; ''; ''};
            app.btnVerEjemplos_15 = uibutton(app.JACOBITab, 'push');
            app.btnVerEjemplos_15.ButtonPushedFcn = createCallbackFcn(app, @btnVerEjemplos_15ButtonPushed, true);
            app.btnVerEjemplos_15.Icon = 'jacobi7.jpg';
            app.btnVerEjemplos_15.Text = '';
            app.CarlGustavJakobJacobiLabel = uilabel(app.JACOBITab);
            app.CarlGustavJakobJacobiLabel.Text = 'Carl Gustav Jakob Jacobi ';
            app.GAUSSSEIDELTab = uitab(app.TabGroup);
            app.GAUSSSEIDELTab.Title = 'GAUSS-SEIDEL';
            app.btnProbarCalculadora_10 = uibutton(app.GAUSSSEIDELTab, 'push');
            app.btnProbarCalculadora_10.ButtonPushedFcn = createCallbackFcn(app, @btnProbarCalculadora_10ButtonPushed, true);
            app.btnProbarCalculadora_10.Text = 'Probar Calculadora';
            app.btnVerEjemplos_10 = uibutton(app.GAUSSSEIDELTab, 'push');
            app.btnVerEjemplos_10.ButtonPushedFcn = createCallbackFcn(app, @btnVerEjemplos_10ButtonPushed, true);
            app.btnVerEjemplos_10.Text = 'Ver Ejemplos';
            app.GAUSSSEIDELLabel = uilabel(app.GAUSSSEIDELTab);
            app.GAUSSSEIDELLabel.Text = 'GAUSS SEIDEL';
            app.Label_14 = uilabel(app.GAUSSSEIDELTab);
            app.Label_14.Text = {'El método Gauss Seidel surgió como modificación al método de Jacobi para acelerar la convergencia, la principal diferencia es que en'; 'el método de Gauss Seidel cada vez que se quiera encontrar un nuevo valor se utilizan también los valores ya encontrados en la'; 'iteración actual.'};
            app.Image9_2 = uiimage(app.GAUSSSEIDELTab);
            app.Image9_2.ImageSource = 'seidel.png';
            app.Label_15 = uilabel(app.GAUSSSEIDELTab);
            app.Label_15.Text = 'Las iteraciones se calculan de la siguiente forma:';
            app.Label_16 = uilabel(app.GAUSSSEIDELTab);
            app.Label_16.Text = {'De la misma manera que en el método Jacobi tenemos que asegurarnos que la matriz es diagonalmente dominante, es decir, los valores'; 'absolutos de los elementos en la diagonal principal deben ser mayores a la suma de valores absolutos de los otros elementos en la fila.'};
            app.Image9_3 = uiimage(app.GAUSSSEIDELTab);
            app.Image9_3.ImageSource = 'seidel2.png';
            app.Label_17 = uilabel(app.GAUSSSEIDELTab);
            app.Label_17.Text = 'En caso de no cumplir con esta condición se debería considerar utilizar otro método como Gauss Seidel con relajación.';
            app.GAUSSSEIDELCONRELAJACINTab = uitab(app.TabGroup);
            app.GAUSSSEIDELCONRELAJACINTab.Title = 'GAUSS-SEIDEL CON RELAJACIÓN';
            app.btnProbarCalculadora = uibutton(app.GAUSSSEIDELCONRELAJACINTab, 'push');
            app.btnProbarCalculadora.ButtonPushedFcn = createCallbackFcn(app, @btnProbarCalculadoraButtonPushed, true);
            app.btnProbarCalculadora.Text = 'Probar Calculadora';
            app.btnVerEjemplos = uibutton(app.GAUSSSEIDELCONRELAJACINTab, 'push');
            app.btnVerEjemplos.ButtonPushedFcn = createCallbackFcn(app, @btnVerEjemplosButtonPushed, true);
            app.btnVerEjemplos.Text = 'Ver Ejemplos';
            app.GAUSSSEIDELCONRELAJACINLabel = uilabel(app.GAUSSSEIDELCONRELAJACINTab);
            app.GAUSSSEIDELCONRELAJACINLabel.Text = 'GAUSS SEIDEL CON RELAJACIÓN';
            app.Label_18 = uilabel(app.GAUSSSEIDELCONRELAJACINTab);
            app.Label_18.Text = {'El método de Gauss Seidel con relajación funciona de la misma forma que el método Gauss Seidel, con la adición del índice de relajación, '; 'el cual es un valor que va entre 0 y 2. Se utiliza ya sea para acelerar la convergencia de un sistema que sea diagonalmente dominante o'; 'para ayudar a que un sistema que no lo sea pueda acercarse a la respuesta.'};
            app.Image9_4 = uiimage(app.GAUSSSEIDELCONRELAJACINTab);
            app.Image9_4.ImageSource = 'seidelrelax.PNG';
            app.Label_19 = uilabel(app.GAUSSSEIDELCONRELAJACINTab);
            app.Label_19.Text = 'Los valores se calculan de la siguiente forma:';
            app.Label_20 = uilabel(app.GAUSSSEIDELCONRELAJACINTab);
            app.Label_20.Text = 'Lambda se encuentra en los siguientes rangos';
            app.Image9_5 = uiimage(app.GAUSSSEIDELCONRELAJACINTab);
            app.Image9_5.ImageSource = 'seidelrelax3.PNG';
            app.Image9_6 = uiimage(app.GAUSSSEIDELCONRELAJACINTab);
            app.Image9_6.ImageSource = 'seidelrelax2.PNG';
            app.Image9_7 = uiimage(app.GAUSSSEIDELCONRELAJACINTab);
            app.Image9_7.ImageSource = 'seidelrelax1.PNG';
            app.Label_21 = uilabel(app.GAUSSSEIDELCONRELAJACINTab);
            app.Label_21.Text = 'Para sistemas no convergentes';
            app.Label_22 = uilabel(app.GAUSSSEIDELCONRELAJACINTab);
            app.Label_22.Text = 'Sub-relajación:';
            app.Label_23 = uilabel(app.GAUSSSEIDELCONRELAJACINTab);
            app.Label_23.Text = 'No hay relajación';
            app.Label_24 = uilabel(app.GAUSSSEIDELCONRELAJACINTab);
            app.Label_24.Text = 'Para sistemas convergentes';
            app.Label_25 = uilabel(app.GAUSSSEIDELCONRELAJACINTab);
            app.Label_25.Text = 'Sobre-relajación:';
            app.NOLINEALESTab = uitab(app.TabGroup2);
            app.NOLINEALESTab.Title = 'NO LINEALES';
            app.TabGroup3 = uitabgroup(app.NOLINEALESTab);
            app.MTODODENEWTONTab = uitab(app.TabGroup3);
            app.MTODODENEWTONTab.Title = 'MÉTODO DE NEWTON';
            app.btnProbarCalculadora_13 = uibutton(app.MTODODENEWTONTab, 'push');
            app.btnProbarCalculadora_13.ButtonPushedFcn = createCallbackFcn(app, @btnProbarCalculadora_13ButtonPushed, true);
            app.btnProbarCalculadora_13.Text = 'Probar Calculadora';
            app.btnVerEjemplos_12 = uibutton(app.MTODODENEWTONTab, 'push');
            app.btnVerEjemplos_12.ButtonPushedFcn = createCallbackFcn(app, @btnVerEjemplos_12ButtonPushed, true);
            app.btnVerEjemplos_12.Text = 'Ver Ejemplos';
            app.MTODODENEWTONLabel = uilabel(app.MTODODENEWTONTab);
            app.MTODODENEWTONLabel.Text = 'MÉTODO DE NEWTON';
            app.Image2_3 = uiimage(app.MTODODENEWTONTab);
            app.Image2_3.ImageSource = 'newton1.PNG';
            app.Label_30 = uilabel(app.MTODODENEWTONTab);
            app.Label_30.Text = 'Entonces los valores de la siguiente iteración son aproximadamente';
            app.Image2_4 = uiimage(app.MTODODENEWTONTab);
            app.Image2_4.ImageSource = 'newton2.PNG';
            app.Label_31 = uilabel(app.MTODODENEWTONTab);
            app.Label_31.Text = 'Escribimos las ecuaciones del sistema de manera general (Por ejemplo en un sistema de 2 ecuaciones sería de la siguiente forma)';
            app.Label_32 = uilabel(app.MTODODENEWTONTab);
            app.Label_32.Text = 'Entonces ->';
            app.Image2_5 = uiimage(app.MTODODENEWTONTab);
            app.Image2_5.ImageSource = 'newton3.PNG';
            app.Label_33 = uilabel(app.MTODODENEWTONTab);
            app.Label_33.Text = 'Matricial ->';
            app.Image2_6 = uiimage(app.MTODODENEWTONTab);
            app.Image2_6.ImageSource = 'newton4.PNG';
            app.Label_34 = uilabel(app.MTODODENEWTONTab);
            app.Label_34.Text = 'Teniendo en cuenta que la segunda matriz es el Jacobiano, despejamos los diferenciales';
            app.Image2_7 = uiimage(app.MTODODENEWTONTab);
            app.Image2_7.ImageSource = 'newton5.PNG';
            app.Label_35 = uilabel(app.MTODODENEWTONTab);
            app.Label_35.Text = 'Entonces las iteraciones se calculan ->';
            app.Image2_8 = uiimage(app.MTODODENEWTONTab);
            app.Image2_8.ImageSource = 'newton6.PNG';
            app.Label_36 = uilabel(app.MTODODENEWTONTab);
            app.Label_36.Text = 'Para n ecuaciones el procedimiento es ->';
            app.Image2_9 = uiimage(app.MTODODENEWTONTab);
            app.Image2_9.ImageSource = 'newton7.PNG';
            app.ButtonTest = uibutton(app.UIFigure, 'push');
            app.ButtonTest.ButtonPushedFcn = createCallbackFcn(app, @ButtonTestPushed2, true);
            app.ButtonTest.Icon = 'test.png';
            app.ButtonTest.Text = '';
            app.ButtonHome = uibutton(app.UIFigure, 'push');
            app.ButtonHome.ButtonPushedFcn = createCallbackFcn(app, @ButtonHomePushed, true);
            app.ButtonHome.Icon = 'home.png';
            app.ButtonHome.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FormularioPrincipal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end