classdef MetodoCholeski < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        A                         matlab.ui.control.Table
        B                         matlab.ui.control.Table
        trans                     matlab.ui.control.TextArea
        deter                     matlab.ui.control.TextArea
        Image                     matlab.ui.control.Image
        Image_2                   matlab.ui.control.Image
        MatrizLLabel              matlab.ui.control.Label
        MatrizDLabel              matlab.ui.control.Label
        ResultadoLabel            matlab.ui.control.Label
        L                         matlab.ui.control.Table
        d                         matlab.ui.control.Table
        x                         matlab.ui.control.Table
        Comprobar                 matlab.ui.control.Button
        Calcular                  matlab.ui.control.Button
        mas                       matlab.ui.control.Button
        menos                     matlab.ui.control.Button
        IngresesusdatosAquiLabel  matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        METODODECHOLESKILabel     matlab.ui.control.Label
        btnRetroceder             matlab.ui.control.Button
        btnExternLink             matlab.ui.control.Button
    end

    
    properties (Access = private)
        n=1; % Description
    end
    
    methods (Access = private)
        
        function results = StartMatriz(app)
            tamanoA=cell(app.n,app.n);
            tamanoA(:,:)={''};
            tamanoB=cell(app.n,1);
            tamanoB(:,:)={''};
            set(app.A,'Data',tamanoA);
            set(app.B,'Data',tamanoB);
            set(app.A,'ColumnEditable',true(1,app.n));
            set(app.B,'ColumnEditable',true(1,app.n));
            for i=1:app.n
                app.A.ColumnName(i)={strcat('x',int2str(i))};
            end
            
        end
        
        function results = sumdeter(app, A)
            results=1;
            for i=1:app.n
                maindiag=1;
                secdig=1;
                t=i;
                for j=1:i
                    maindiag=maindiag*A(j,j);
                    secdig=secdig*A(j,t);
                    t=t-1;
                end
                if((maindiag-secdig)<0)
                    app.deter.Value=strsplit(strcat('Error:determinante de matriz',num2str(i),'x',num2str(i),' es: ',num2str(maindiag-secdig)));
                    results=0;
                    return;
                end
            end
            app.deter.Value="Matriz correcta";
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function masButtonPushed(app, event)
            app.n=app.n+1;
            StartMatriz(app);
            
        end

        % Callback function
        function menosButtonPushed(app, event)
            app.n=app.n-1;
            StartMatriz(app);
        end

        % Callback function
        function ComprobarButtonPushed(app, event)
            data=get(app.TableData,'data');
            data=str2double(data);  
           
            app.trans.Value=num2str(sumdeter(app,data));
            if(data==transpose(data))
                app.trans.Value="Matriz correcta";    
                if((sumdeter(app,data)==1) )
                   app.Calcular.Enable='on';
                end
            else
                app.trans.Value="Error: La matriz no es igual a su transpuesta";
                
            end
            app.Comprobar.Enable='off';
           

        end

        % Callback function
        function calcularButtonPushed(app, event)
             A=get(app.A,'data');
             A=str2double(A);
             
             b=get(app.B,'data');
             b=str2double(b);
             
             L=transpose(chol(A));
             d=inv(L)*b;
             x=inv(transpose(L))*d;
             
             app.L.Data=L;
             app.d.Data=d;
             app.x.Data=x;
             app.Calcular.Enable='off';
             app.Comprobar.Enable='on';
              
             
        end

        % Button pushed function: Comprobar
        function ComprobarButtonPushed2(app, event)
            data=get(app.A,'data');
            data=str2double(data);  
           
            app.trans.Value=num2str(sumdeter(app,data));
            if(data==transpose(data))
                app.trans.Value="Matriz correcta";    
                if((sumdeter(app,data)==1) )
                   app.Calcular.Enable='on';
                end
            else
                app.trans.Value="Error: La matriz no es igual a su transpuesta";
                
            end
            app.Comprobar.Enable='off';
        end

        % Button pushed function: Calcular
        function CalcularButtonPushed(app, event)
              A=get(app.A,'data');
             A=str2double(A);
             
             b=get(app.B,'data');
             b=str2double(b);
             
             L=transpose(chol(A));
             d=inv(L)*b;
             x=inv(transpose(L))*d;
             
             app.L.Data=L;
             app.d.Data=d;
             app.x.Data=x;
             app.Calcular.Enable='off';
             app.Comprobar.Enable='on';
              
        end

        % Button pushed function: mas
        function masButtonPushed2(app, event)
            app.n=app.n+1;
            StartMatriz(app);
            
        end

        % Button pushed function: menos
        function menosButtonPushed2(app, event)
            app.n=app.n-1;
            StartMatriz(app);
            
        end

        % Button pushed function: btnRetroceder
        function btnRetrocederButtonPushed(app, event)
            FormularioPrincipal;
            delete(app);
        end

        % Button pushed function: btnExternLink
        function btnExternLinkButtonPushed(app, event)
            web('https://www.youtube.com/watch?v=fgUl_nlb0J4');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.A = uitable(app.UIFigure);
            app.A.ColumnName = {''};
            app.A.RowName = {};
            app.B = uitable(app.UIFigure);
            app.B.ColumnName = {'B'};
            app.B.RowName = {};
            app.trans = uitextarea(app.UIFigure);
            app.trans.Editable = 'off';
            app.deter = uitextarea(app.UIFigure);
            app.deter.Editable = 'off';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'comp2.PNG';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'comp1.PNG';
            app.MatrizLLabel = uilabel(app.UIFigure);
            app.MatrizLLabel.Text = 'Matriz L';
            app.MatrizDLabel = uilabel(app.UIFigure);
            app.MatrizDLabel.Text = 'Matriz D';
            app.ResultadoLabel = uilabel(app.UIFigure);
            app.ResultadoLabel.Text = 'Resultado';
            app.L = uitable(app.UIFigure);
            app.L.ColumnName = '';
            app.L.RowName = {};
            app.d = uitable(app.UIFigure);
            app.d.ColumnName = '';
            app.d.RowName = {};
            app.x = uitable(app.UIFigure);
            app.x.ColumnName = '';
            app.x.RowName = {};
            app.Comprobar = uibutton(app.UIFigure, 'push');
            app.Comprobar.ButtonPushedFcn = createCallbackFcn(app, @ComprobarButtonPushed2, true);
            app.Comprobar.Text = 'Comprobar';
            app.Calcular = uibutton(app.UIFigure, 'push');
            app.Calcular.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.Calcular.Enable = 'off';
            app.Calcular.Text = 'Calcular';
            app.mas = uibutton(app.UIFigure, 'push');
            app.mas.ButtonPushedFcn = createCallbackFcn(app, @masButtonPushed2, true);
            app.mas.Text = {'+'; ''};
            app.menos = uibutton(app.UIFigure, 'push');
            app.menos.ButtonPushedFcn = createCallbackFcn(app, @menosButtonPushed2, true);
            app.menos.Text = {'-'; ''};
            app.IngresesusdatosAquiLabel = uilabel(app.UIFigure);
            app.IngresesusdatosAquiLabel.Text = 'Ingrese sus datos Aqui:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '=';
            app.METODODECHOLESKILabel = uilabel(app.UIFigure);
            app.METODODECHOLESKILabel.Text = 'METODO DE CHOLESKI';
            app.btnRetroceder = uibutton(app.UIFigure, 'push');
            app.btnRetroceder.ButtonPushedFcn = createCallbackFcn(app, @btnRetrocederButtonPushed, true);
            app.btnRetroceder.Icon = 'back.png';
            app.btnRetroceder.Text = '';
            app.btnExternLink = uibutton(app.UIFigure, 'push');
            app.btnExternLink.ButtonPushedFcn = createCallbackFcn(app, @btnExternLinkButtonPushed, true);
            app.btnExternLink.Icon = 'extern-link.png';
            app.btnExternLink.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetodoCholeski

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end