classdef MetodoCramer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RegladeCramerUIFigure          matlab.ui.Figure
        CalcularButton                 matlab.ui.control.Button
        ResultadoLabel                 matlab.ui.control.Label
        TableData                      matlab.ui.control.Table
        TableResultado                 matlab.ui.control.Table
        NmerodevariablesDropDownLabel  matlab.ui.control.Label
        NumeroDeVariablesDropDown      matlab.ui.control.DropDown
        REGLADECRAMERLabel             matlab.ui.control.Label
        btnRetroceder                  matlab.ui.control.Button
        btnExternLink                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.TableData, 'ColumnEditable', true)
            row = str2double(app.NumeroDeVariablesDropDown.Value);
            col = row + 1 ;
            set(app.TableData, 'ColumnName', {'X1','X2','Z'});
            
            set(app.TableData, 'Data', cell(row,col))
        end

        % Value changed function: NumeroDeVariablesDropDown
        function NumeroDeVariablesDropDownValueChanged(app, event)
            row = str2double(app.NumeroDeVariablesDropDown.Value);
            col = row + 1 ;
            set(app.TableData, 'ColumnEditable', true)
            
            switch(row)
                case 2
                    set(app.TableData, 'ColumnName', {'X1','X2','Z'});
                case 3
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','Z'});
                case 4
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','Z'});
                case 5
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','Z'});
                case 6
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','X6','Z'});
                case 7
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','Z'});
                case 8
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','X8','Z'});
                case 9
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','X8','X9','Z'});
                case 10
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','X8','X9','X10','Z'});
            end
            set(app.TableResultado, 'Data', {})
            set(app.TableData, 'Data', cell(row,col))
            
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            row = str2double(app.NumeroDeVariablesDropDown.Value);
            matrizData = get(app.TableData, 'Data');
            matrizData = str2double(matrizData);
            disp(matrizData);
            
            y = zeros(row);
            x=zeros(1,row);
            
            for i=1:size(matrizData, 1)
                for j=1:size(matrizData, 2)
                    if(j == size(matrizData, 2))
                        x(i,j) = matrizData(i,j);
                    else
                        y(i,j) = matrizData(i,j);
                    end
                end
            end
     
            B=x(:,end);
            A=y;
            
            deterA=det(A);
            disp(deterA);
            P=A;
            cont=0;
            valueVar=zeros(size(B));
            for cont=1:size(B)
                [fA,cA]=size(A);
                for i=1:fA
                    for j=1:fA
                        P(i,j)=A(i,j);
                    end
                end
                deterMom=0;
                for j=1:size(B)
                    P(j,cont)=B(j);
                end
                deterMom=det(P);
                disp(deterMom);
                valueVar(cont)=deterMom/deterA;
            end
            
            switch(size(valueVar,1))
                case 2
                    set(app.TableResultado, 'RowName', {'X1','X2'}, 'Data', x');
                case 3
                    set(app.TableResultado, 'RowName', {'X1','X2','X3'}, 'Data', x');
                case 4
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4'}, 'Data', x');
                case 5
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5'}, 'Data', x');
                case 6
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5','X6'}, 'Data', x');
                case 7
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5','X6','X7'}, 'Data', x');
                case 8
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5','X6','X7','X8'}, 'Data', x');
                case 9
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5','X6','X7','X8','X9'}, 'Data', x');
                case 10
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5','X6','X7','X8','X9','X10'}, 'Data', x');
            end
             set(app.TableResultado, 'ColumnName','Valor');
            set(app.TableResultado, 'Data', valueVar);
  
        end

        % Button pushed function: btnRetroceder
        function btnRetrocederButtonPushed(app, event)
            FormularioPrincipal;
            delete(app);
        end

        % Button pushed function: btnExternLink
        function btnExternLinkButtonPushed(app, event)
            web('https://youtu.be/opZbv0eGaFE')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RegladeCramerUIFigure = uifigure('Visible', 'off');
            app.RegladeCramerUIFigure.Name = 'Regla de Cramer';
            app.CalcularButton = uibutton(app.RegladeCramerUIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.ResultadoLabel = uilabel(app.RegladeCramerUIFigure);
            app.ResultadoLabel.Text = 'Resultado';
            app.TableData = uitable(app.RegladeCramerUIFigure);
            app.TableData.ColumnName = '';
            app.TableData.RowName = {};
            app.TableResultado = uitable(app.RegladeCramerUIFigure);
            app.TableResultado.ColumnName = '';
            app.TableResultado.RowName = {};
            app.NmerodevariablesDropDownLabel = uilabel(app.RegladeCramerUIFigure);
            app.NmerodevariablesDropDownLabel.Text = 'Número de variables:';
            app.NumeroDeVariablesDropDown = uidropdown(app.RegladeCramerUIFigure);
            app.NumeroDeVariablesDropDown.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.NumeroDeVariablesDropDown.ValueChangedFcn = createCallbackFcn(app, @NumeroDeVariablesDropDownValueChanged, true);
            app.NumeroDeVariablesDropDown.Value = '2';
            app.REGLADECRAMERLabel = uilabel(app.RegladeCramerUIFigure);
            app.REGLADECRAMERLabel.Text = 'REGLA DE CRAMER';
            app.btnRetroceder = uibutton(app.RegladeCramerUIFigure, 'push');
            app.btnRetroceder.ButtonPushedFcn = createCallbackFcn(app, @btnRetrocederButtonPushed, true);
            app.btnRetroceder.Icon = 'back.png';
            app.btnRetroceder.Text = '';
            app.btnExternLink = uibutton(app.RegladeCramerUIFigure, 'push');
            app.btnExternLink.ButtonPushedFcn = createCallbackFcn(app, @btnExternLinkButtonPushed, true);
            app.btnExternLink.Icon = 'extern-link.png';
            app.btnExternLink.Text = '';
            % Show the figure after all components are created
            app.RegladeCramerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetodoCramer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RegladeCramerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RegladeCramerUIFigure)
        end
    end
end