classdef MetodoDeEliminacionDeGauss < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MtododeEliminacindeGaussUIFigure  matlab.ui.Figure
        NmerodevariablesDropDownLabel  matlab.ui.control.Label
        NumeroDeVariablesDropDown      matlab.ui.control.DropDown
        btnCalcular                    matlab.ui.control.Button
        ResultadoLabel                 matlab.ui.control.Label
        TableData                      matlab.ui.control.Table
        TableResultado                 matlab.ui.control.Table
        TableResultadoMatriz           matlab.ui.control.Table
        TablafinalLabel                matlab.ui.control.Label
        ELIMINACINDEGAUSSLabel         matlab.ui.control.Label
        btnRetroceder                  matlab.ui.control.Button
        btnExternLink                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        set(app.TableData, 'ColumnEditable', true)
            row = str2double(app.NumeroDeVariablesDropDown.Value);
            col = row + 1 ;
            set(app.TableData, 'ColumnName', {'X1','X2','Z'});
            
            set(app.TableData, 'Data', cell(row,col))
        end

        % Value changed function: NumeroDeVariablesDropDown
        function NumeroDeVariablesDropDownValueChanged(app, event)
            row = str2double(app.NumeroDeVariablesDropDown.Value);
            col = row + 1 ;
            
            set(app.TableResultadoMatriz, 'Data', {})
            set(app.TableResultado, 'Data', {})
            switch(row)
                case 2
                    set(app.TableData, 'ColumnName', {'X1','X2','Z'});
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','Z'});
                case 3
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','Z'});
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','Z'});
                case 4
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','Z'});
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','Z'});
                case 5
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','Z'});
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','X5','Z'});
                case 6
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','X6','Z'});
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','X5','X6','Z'});
                case 7
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','Z'});
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','Z'});
                case 8
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','X8','Z'});
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','X8','Z'});
                case 9
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','X8','X9','Z'});
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','X8','X9','Z'});
                case 10
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','X8','X9','X10','Z'});
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','X8','X9','X10','Z'});
            end
            set(app.TableData, 'Data', cell(row,col))
        end

        % Button pushed function: btnCalcular
        function btnCalcularPushed(app, event)
            
            matrizData = get(app.TableData, 'Data');
            matrizData = str2double(matrizData);
            
            row = size(matrizData,1);
            col = size(matrizData,2);
            [row,col]=size(matrizData);
            for j=1:row-1
                for z=2:row
                    if matrizData(j,j)==0
                        t=matrizData(j,:);matrizData(j,:)=matrizData(z,:);
                        matrizData(z,:)=t;
                    end
                end
                for i=j+1:row
                    matrizData(i,:)=matrizData(i,:)-matrizData(j,:)*(matrizData(i,j)/matrizData(j,j));
                end
            end
            x=zeros(1,row);
            for s=row:-1:1
                c=0;
                for k=2:row
                    c=c+matrizData(s,k)*x(k);
                end
                x(s)=(matrizData(s,col)-c)/matrizData(s,s);
            end
            
            tmnMatrizResult = size(matrizData, 1);
            
            switch(tmnMatrizResult)
                case 2
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','Z'});
                    set(app.TableResultado, 'RowName', {'X1','X2'}, 'Data', x');
                case 3
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','Z'});
                    set(app.TableResultado, 'RowName', {'X1','X2','X3'}, 'Data', x');
                case 4
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','Z'});
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4'}, 'Data', x');
                case 5
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','X5','Z'});
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5'}, 'Data', x');
                case 6
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','X5','X6','Z'});
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5','X6'}, 'Data', x');
                case 7
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','Z'});
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5','X6','X7'}, 'Data', x');
                case 8
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','X8','Z'});
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5','X6','X7','X8'}, 'Data', x');
                case 9
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','X8','X9','Z'});
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5','X6','X7','X8','X9'}, 'Data', x');
                case 10
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','X8','X9','X10','Z'});
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5','X6','X7','X8','X9','X10'}, 'Data', x');
            end
            set(app.TableResultado, 'ColumnName','Valor');
            set(app.TableResultadoMatriz, 'Data', matrizData)
            
        end

        % Button pushed function: btnRetroceder
        function btnRetrocederButtonPushed(app, event)
            FormularioPrincipal;
            delete(app);
        end

        % Button pushed function: btnExternLink
        function btnExternLinkButtonPushed(app, event)
            web('https://www.youtube.com/watch?v=SxT5Sbn8odE')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MtododeEliminacindeGaussUIFigure = uifigure('Visible', 'off');
            app.MtododeEliminacindeGaussUIFigure.Name = 'Método de Eliminación de Gauss';
            app.NmerodevariablesDropDownLabel = uilabel(app.MtododeEliminacindeGaussUIFigure);
            app.NmerodevariablesDropDownLabel.Text = 'Número de variables:';
            app.NumeroDeVariablesDropDown = uidropdown(app.MtododeEliminacindeGaussUIFigure);
            app.NumeroDeVariablesDropDown.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.NumeroDeVariablesDropDown.ValueChangedFcn = createCallbackFcn(app, @NumeroDeVariablesDropDownValueChanged, true);
            app.NumeroDeVariablesDropDown.Value = '2';
            app.btnCalcular = uibutton(app.MtododeEliminacindeGaussUIFigure, 'push');
            app.btnCalcular.ButtonPushedFcn = createCallbackFcn(app, @btnCalcularPushed, true);
            app.btnCalcular.Text = 'Calcular';
            app.ResultadoLabel = uilabel(app.MtododeEliminacindeGaussUIFigure);
            app.ResultadoLabel.Text = 'Resultado';
            app.TableData = uitable(app.MtododeEliminacindeGaussUIFigure);
            app.TableData.ColumnName = '';
            app.TableData.RowName = {};
            app.TableResultado = uitable(app.MtododeEliminacindeGaussUIFigure);
            app.TableResultado.ColumnName = '';
            app.TableResultado.RowName = {};
            app.TableResultadoMatriz = uitable(app.MtododeEliminacindeGaussUIFigure);
            app.TableResultadoMatriz.ColumnName = '';
            app.TableResultadoMatriz.RowName = {};
            app.TablafinalLabel = uilabel(app.MtododeEliminacindeGaussUIFigure);
            app.TablafinalLabel.Text = 'Tabla final:';
            app.ELIMINACINDEGAUSSLabel = uilabel(app.MtododeEliminacindeGaussUIFigure);
            app.ELIMINACINDEGAUSSLabel.Text = 'ELIMINACIÓN DE GAUSS';
            app.btnRetroceder = uibutton(app.MtododeEliminacindeGaussUIFigure, 'push');
            app.btnRetroceder.ButtonPushedFcn = createCallbackFcn(app, @btnRetrocederButtonPushed, true);
            app.btnRetroceder.Icon = 'back.png';
            app.btnRetroceder.Text = '';
            app.btnExternLink = uibutton(app.MtododeEliminacindeGaussUIFigure, 'push');
            app.btnExternLink.ButtonPushedFcn = createCallbackFcn(app, @btnExternLinkButtonPushed, true);
            app.btnExternLink.Icon = 'extern-link.png';
            app.btnExternLink.Text = '';
            % Show the figure after all components are created
            app.MtododeEliminacindeGaussUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetodoDeEliminacionDeGauss

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MtododeEliminacindeGaussUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MtododeEliminacindeGaussUIFigure)
        end
    end
end