classdef MetodoFactorizacionCROUD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FactorizacindeCROUDUIFigure    matlab.ui.Figure
        CalcularButton                 matlab.ui.control.Button
        ResultadoLabel                 matlab.ui.control.Label
        TableData                      matlab.ui.control.Table
        TableResultado                 matlab.ui.control.Table
        NmerodevariablesDropDownLabel  matlab.ui.control.Label
        NumeroDeVariablesDropDown      matlab.ui.control.DropDown
        FACTORIZACIONCROUDLabel        matlab.ui.control.Label
        btnRetroceder                  matlab.ui.control.Button
        btnExternLink                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.TableData, 'ColumnEditable', true)
            row = str2double(app.NumeroDeVariablesDropDown.Value);
            col = row + 1 ;
            set(app.TableData, 'ColumnName', {'X1','X2','Z'});
            
            set(app.TableData, 'Data', cell(row,col))
        end

        % Value changed function: NumeroDeVariablesDropDown
        function NumeroDeVariablesDropDownValueChanged(app, event)
            row = str2double(app.NumeroDeVariablesDropDown.Value);
            col = row + 1 ;
            set(app.TableData, 'ColumnEditable', true)
            
            switch(row)
                case 2
                    set(app.TableData, 'ColumnName', {'X1','X2','Z'});
                case 3
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','Z'});
                case 4
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','Z'});
                case 5
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','Z'});
                case 6
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','X6','Z'});
                case 7
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','Z'});
                case 8
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','X8','Z'});
                case 9
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','X8','X9','Z'});
                case 10
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','X8','X9','X10','Z'});
            end
            set(app.TableResultado, 'Data', {})
            set(app.TableData, 'Data', cell(row,col))
            
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            row = str2double(app.NumeroDeVariablesDropDown.Value);
            matrizData = get(app.TableData, 'Data');
            matrizData = str2double(matrizData);
            disp(matrizData);
            
            y = zeros(row);
            x=zeros(1,row);
            
            for i=1:size(matrizData, 1)
                for j=1:size(matrizData, 2)
                    if(j == size(matrizData, 2))
                        x(i,j) = matrizData(i,j);
                    else
                        y(i,j) = matrizData(i,j);
                    end
                end
            end
     
            B=x(:,end);
            A=y;
            
            [n,n]=size(A);
            for k=1:n
                L(k,k)=1;
                for i=k+1:n
                    L(i,k)=A(i,k)/A(k,k);
                    for j=k+1:n
                        A(i,j)=A(i,j)-L(i,k)*A(k,j);
                    end
                end
                for j=k:n
                    U(k,j)=A(k,j);
                end   
            end
            %disp("L");
            %disp(L);
            %disp("U");
            %disp(U);
            z=inv(L)*B;
            rpta=inv(U)*z;
            %disp(rpta);
            
            switch(size(rpta,1))
                case 2
                    set(app.TableResultado, 'RowName', {'X1','X2'}, 'Data', x');
                case 3
                    set(app.TableResultado, 'RowName', {'X1','X2','X3'}, 'Data', x');
                case 4
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4'}, 'Data', x');
                case 5
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5'}, 'Data', x');
                case 6
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5','X6'}, 'Data', x');
                case 7
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5','X6','X7'}, 'Data', x');
                case 8
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5','X6','X7','X8'}, 'Data', x');
                case 9
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5','X6','X7','X8','X9'}, 'Data', x');
                case 10
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5','X6','X7','X8','X9','X10'}, 'Data', x');
            end
             set(app.TableResultado, 'ColumnName','Valor');
            set(app.TableResultado, 'Data', rpta);
        end

        % Button pushed function: btnRetroceder
        function btnRetrocederButtonPushed(app, event)
            FormularioPrincipal;
            delete(app);
        end

        % Button pushed function: btnExternLink
        function btnExternLinkButtonPushed(app, event)
            web('https://youtu.be/PJX8jwjlj5w?t=7')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FactorizacindeCROUDUIFigure = uifigure('Visible', 'off');
            app.FactorizacindeCROUDUIFigure.Name = 'Factorización de CROUD';
            app.CalcularButton = uibutton(app.FactorizacindeCROUDUIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.ResultadoLabel = uilabel(app.FactorizacindeCROUDUIFigure);
            app.ResultadoLabel.Text = 'Resultado';
            app.TableData = uitable(app.FactorizacindeCROUDUIFigure);
            app.TableData.ColumnName = '';
            app.TableData.RowName = {};
            app.TableResultado = uitable(app.FactorizacindeCROUDUIFigure);
            app.TableResultado.ColumnName = '';
            app.TableResultado.RowName = {};
            app.NmerodevariablesDropDownLabel = uilabel(app.FactorizacindeCROUDUIFigure);
            app.NmerodevariablesDropDownLabel.Text = 'Número de variables:';
            app.NumeroDeVariablesDropDown = uidropdown(app.FactorizacindeCROUDUIFigure);
            app.NumeroDeVariablesDropDown.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.NumeroDeVariablesDropDown.ValueChangedFcn = createCallbackFcn(app, @NumeroDeVariablesDropDownValueChanged, true);
            app.NumeroDeVariablesDropDown.Value = '2';
            app.FACTORIZACIONCROUDLabel = uilabel(app.FactorizacindeCROUDUIFigure);
            app.FACTORIZACIONCROUDLabel.Text = 'FACTORIZACION CROUD';
            app.btnRetroceder = uibutton(app.FactorizacindeCROUDUIFigure, 'push');
            app.btnRetroceder.ButtonPushedFcn = createCallbackFcn(app, @btnRetrocederButtonPushed, true);
            app.btnRetroceder.Icon = 'back.png';
            app.btnRetroceder.Text = '';
            app.btnExternLink = uibutton(app.FactorizacindeCROUDUIFigure, 'push');
            app.btnExternLink.ButtonPushedFcn = createCallbackFcn(app, @btnExternLinkButtonPushed, true);
            app.btnExternLink.Icon = 'extern-link.png';
            app.btnExternLink.Text = '';
            % Show the figure after all components are created
            app.FactorizacindeCROUDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetodoFactorizacionCROUD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FactorizacindeCROUDUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FactorizacindeCROUDUIFigure)
        end
    end
end