classdef MetodoGaussSeidelRelajacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RESOLVERButton                matlab.ui.control.Button
        ErrorEditFieldLabel           matlab.ui.control.Label
        ErrorEditField                matlab.ui.control.NumericEditField
        IteracionesLabel              matlab.ui.control.Label
        ComprobarButton               matlab.ui.control.Button
        AgregarcolumnaButton          matlab.ui.control.Button
        QuitarcolumnaButton           matlab.ui.control.Button
        NmerodeiteracionesEditFieldLabel  matlab.ui.control.Label
        Iteraciones                   matlab.ui.control.NumericEditField
        Ecuaciones                    matlab.ui.control.Table
        Resultados                    matlab.ui.control.Table
        GAUSSSEIDELCONRELAJACINLabel  matlab.ui.control.Label
        EcuacionesLabel               matlab.ui.control.Label
        RelajacinEditFieldLabel       matlab.ui.control.Label
        RelajacinEditField            matlab.ui.control.NumericEditField
        btnRetroceder                 matlab.ui.control.Button
        btnExternLink                 matlab.ui.control.Button
        Recom                         matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        n=3; % Description
        iter=3; % Description
        relajacion=1; % Description
    end
    
    methods (Access = private)
        
        function startMatrix(app)
            sizeA = cell(app.n-1, app.n);
            sizeA(:,:) = {''};
            set(app.Ecuaciones, 'Data', sizeA);
            set(app.Ecuaciones, 'ColumnEditable', true(1, app.n));
            for i=1:app.n
                app.Ecuaciones.ColumnName(i) = {strcat('x', int2str(i))};
            end
            for i=1:app.n-1
                app.Ecuaciones.RowName(i) = {strcat('e', int2str(i))};
            end
        end
        
        function startResultMatrix(app)
            sizeA = cell(app.iter, app.n-1);
            sizeA(:,:) = {''};
            set(app.Resultados, 'Data', sizeA);
            set(app.Resultados, 'ColumnEditable', false);
            for i=1:app.n-1
                app.Resultados.ColumnName(i) = {strcat('x', int2str(i))};
            end
            for i=1:app.iter
                app.Resultados.RowName(i) = {strcat('i', int2str(i))};
            end
        end
        
        function gaussSeidel(app, variables)
            varNum = app.n-1;
            answers = zeros([app.iter, varNum]);
            currentIter = zeros([varNum,1]);
            for i=2:app.iter
                for j=1:varNum
                    for k=1:size(currentIter)
                        if(k<j)
                            currentIter(k) = answers(i, k);
                        else
                            currentIter(k) = answers(i-1, k);
                        end
                    end
                    newVect = currentIter([1:j-1 j+1:end]);
                    newCoef = variables(j,[1:j-1 j+1:end-1]);
                    answers(i, j) = sum((variables(j, end)-sum(newVect.*newCoef'))/variables(j, j))*app.relajacion;
                    currentIter = zeros(varNum,1);
                end
            end
            set(app.Resultados, 'Data', answers);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startMatrix(app);
            startResultMatrix(app);
        end

        % Button pushed function: AgregarcolumnaButton
        function AgregarcolumnaButtonPushed(app, event)
            app.n = app.n+1;
            startMatrix(app);
            startResultMatrix(app);
        end

        % Button pushed function: QuitarcolumnaButton
        function QuitarcolumnaButtonPushed(app, event)
            if app.n>3
                app.n = app.n-1;
                startMatrix(app);
                startResultMatrix(app);
            end
        end

        % Value changed function: Iteraciones
        function IteracionesValueChanged(app, event)
            app.iter = app.Iteraciones.Value;
            startResultMatrix(app);
        end

        % Button pushed function: RESOLVERButton
        function RESOLVERButtonPushed(app, event)
            matrix = get(app.Ecuaciones, 'Data');
            matrix = str2double(matrix);
            gaussSeidel(app, matrix);
        end

        % Value changed function: RelajacinEditField
        function RelajacinEditFieldValueChanged(app, event)
            app.relajacion = app.RelajacinEditField.Value;
        end

        % Button pushed function: btnRetroceder
        function btnRetrocederButtonPushed(app, event)
            FormularioPrincipal;
            delete(app);
        end

        % Button pushed function: ComprobarButton
        function ComprobarButtonPushed(app, event)
            matrix = get(app.Ecuaciones, 'Data');
            matrix = str2double(matrix);
            for i=1:app.n-1
                sum=0;
                current_value = 0;
                for j=1:app.n-1
                    value_abs = abs(matrix(i, j));
                    if j==i
                        current_value = value_abs;
                    else
                        sum = sum + value_abs;
                    end
                    
                end
                if current_value > sum
                    set(app.Recom, 'Value', 'El sistema converge');
                else
                    set(app.Recom, 'Value', 'Pruebe usando el metodo Gauss Seidel con relajacion < 1');
                end
            end
        end

        % Button pushed function: btnExternLink
        function btnExternLinkButtonPushed(app, event)
            web('https://www.youtube.com/watch?v=tgK9zh6iu6M');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Método de Gauss Seidel con Relajación';
            app.RESOLVERButton = uibutton(app.UIFigure, 'push');
            app.RESOLVERButton.ButtonPushedFcn = createCallbackFcn(app, @RESOLVERButtonPushed, true);
            app.RESOLVERButton.Text = 'Calcular';
            app.ErrorEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorEditFieldLabel.Text = 'Error';
            app.ErrorEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErrorEditField.Value = 0.01;
            app.IteracionesLabel = uilabel(app.UIFigure);
            app.IteracionesLabel.Text = 'Iteraciones';
            app.ComprobarButton = uibutton(app.UIFigure, 'push');
            app.ComprobarButton.ButtonPushedFcn = createCallbackFcn(app, @ComprobarButtonPushed, true);
            app.ComprobarButton.Text = 'Comprobar';
            app.AgregarcolumnaButton = uibutton(app.UIFigure, 'push');
            app.AgregarcolumnaButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarcolumnaButtonPushed, true);
            app.AgregarcolumnaButton.Text = 'Agregar';
            app.QuitarcolumnaButton = uibutton(app.UIFigure, 'push');
            app.QuitarcolumnaButton.ButtonPushedFcn = createCallbackFcn(app, @QuitarcolumnaButtonPushed, true);
            app.QuitarcolumnaButton.Text = 'Quitar';
            app.NmerodeiteracionesEditFieldLabel = uilabel(app.UIFigure);
            app.NmerodeiteracionesEditFieldLabel.Text = 'Número de iteraciones:';
            app.Iteraciones = uieditfield(app.UIFigure, 'numeric');
            app.Iteraciones.ValueChangedFcn = createCallbackFcn(app, @IteracionesValueChanged, true);
            app.Iteraciones.Value = 3;
            app.Ecuaciones = uitable(app.UIFigure);
            app.Ecuaciones.ColumnName = {''};
            app.Ecuaciones.RowName = {};
            app.Resultados = uitable(app.UIFigure);
            app.Resultados.ColumnName = {''};
            app.Resultados.RowName = {};
            app.GAUSSSEIDELCONRELAJACINLabel = uilabel(app.UIFigure);
            app.GAUSSSEIDELCONRELAJACINLabel.Text = 'GAUSS SEIDEL CON RELAJACIÓN';
            app.EcuacionesLabel = uilabel(app.UIFigure);
            app.EcuacionesLabel.Text = 'Ecuaciones';
            app.RelajacinEditFieldLabel = uilabel(app.UIFigure);
            app.RelajacinEditFieldLabel.Text = 'Relajación';
            app.RelajacinEditField = uieditfield(app.UIFigure, 'numeric');
            app.RelajacinEditField.ValueChangedFcn = createCallbackFcn(app, @RelajacinEditFieldValueChanged, true);
            app.RelajacinEditField.Value = 1;
            app.btnRetroceder = uibutton(app.UIFigure, 'push');
            app.btnRetroceder.ButtonPushedFcn = createCallbackFcn(app, @btnRetrocederButtonPushed, true);
            app.btnRetroceder.Icon = 'back.png';
            app.btnRetroceder.Text = '';
            app.btnExternLink = uibutton(app.UIFigure, 'push');
            app.btnExternLink.ButtonPushedFcn = createCallbackFcn(app, @btnExternLinkButtonPushed, true);
            app.btnExternLink.Icon = 'extern-link.png';
            app.btnExternLink.Text = '';
            app.Recom = uitextarea(app.UIFigure);
            app.Recom.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetodoGaussSeidelRelajacion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end