classdef MetodoJacobi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        A                         matlab.ui.control.Table
        mas                       matlab.ui.control.Button
        menos                     matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        B                         matlab.ui.control.Table
        r                         matlab.ui.control.TextArea
        ErrorInicio               matlab.ui.control.NumericEditField
        IngresesusdatosAquiLabel  matlab.ui.control.Label
        ErrorLabel                matlab.ui.control.Label
        NInteraciones             matlab.ui.control.NumericEditField
        NIteracionesLabel         matlab.ui.control.Label
        S                         matlab.ui.control.Table
        Comprobar                 matlab.ui.control.Button
        METODODEJACOBILabel       matlab.ui.control.Label
        Calcular                  matlab.ui.control.Button
        btnRetroceder             matlab.ui.control.Button
        btnExternLink             matlab.ui.control.Button
    end

    
    properties (Access = private)
        n=1; % Description
    end
    
    methods (Access = private)
        
        function results = StartMatriz(app)
            tamanoA=cell(app.n,app.n);
            tamanoA(:,:)={''};
            tamanoB=cell(app.n,1);
            tamanoB(:,:)={''};
            set(app.A,'Data',tamanoA);
            set(app.B,'Data',tamanoB);
            set(app.A,'ColumnEditable',true(1,app.n));
            set(app.B,'ColumnEditable',true(1,app.n));
            
            
            for i=1:app.n
                app.A.ColumnName(i)={strcat('x',int2str(i))};
            end
          
        end
        function results = Jacobi(app,A,b,x,tol,m)
            n=length(x);
            app.r.Value=num2str(n);
            for k=1:m
                w=x;
                
                for i=1:n
                    s=A(i,1:i-1)*w(1:i-1)+A(i,i+1:n)*w(i+1:n);
                    x(i)=(b(i)-s)/A(i,i);
                end
                if norm(w-x,inf)<tol
                    return
                end
                fprintf('\n la solucion del sistema en la iteracion %4.0f\n',k)
                for i=1:n
                    %fprintf(' x(%1.0f)=%6.8f\n',i,x(i))
                    data(k,i)=x(i);
                end
                data(k,n+1)=norm(w-x,inf);
            end
            app.S.Data=data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: mas
        function masButtonPushed(app, event)
            app.n=app.n+1;
            StartMatriz(app);
            
        end

        % Button pushed function: menos
        function menosButtonPushed(app, event)
            app.n=app.n-1;
            StartMatriz(app);
        end

        % Callback function
        function ComprobarButtonPushed(app, event)
          N=get(app.A,'data');
          N=str2double(N); 
          U=get(app.B,'data');
          U=str2double(U); 
         for i=1:app.n
            f=0; 
            tmp=abs(N);
            [Mc,Ic]=max(tmp(:,i));
            if(tmp(i,i)~=Mc)
                N([i Ic],:)=N([Ic i],:);
                %tmp([i Ic],:)=tmp([i Ic],:);
                U([i Ic],:)=U([Ic i],:);
                f=f+1;
            end
            tmp=abs(N);
            [Mf,If]=max(tmp(i,:));
            if((tmp(i,i))~=Mf)
                N(:,[i If])=N(:,[If i]);
                %tmp(:,[i If])=tmp(:,[If i]);
                f=f+1;
            end 
            if(f==2)
                 app.r.Value=strcat("Error: No se puede reordenar la matriz ",num2str(f));
                 return;
            end
          end   
          app.r.Value="Exito: Se reordeno la matriz";
          app.B.Data=U;
          app.A.Data=N;
          app.Calcular.Enable='on';
         
        
        end

        % Callback function
        function CalcularButtonPushed(app, event)
            error=app.ErrorInicio.Value;
            error=str2double(error);
            iteraciones=app.NInteraciones.Value;
            %iteraciones=str2double(iteraciones);
            A=get(app.A,'data');
            %A=str2double(A); 
            b=get(app.B,'data');
            %b=str2double(b)
            %b=b';
            x=zeros(app.n,1);
           
            Jacobi(app,A,b,x,error,iteraciones);
        end

        % Button pushed function: Comprobar
        function ComprobarButtonPushed2(app, event)
            N=get(app.A,'data');
          N=str2double(N); 
          U=get(app.B,'data');
          U=str2double(U); 
         for i=1:app.n
            f=0; 
            tmp=abs(N);
            [Mc,Ic]=max(tmp(:,i));
            if(tmp(i,i)~=Mc)
                N([i Ic],:)=N([Ic i],:);
                %tmp([i Ic],:)=tmp([i Ic],:);
                U([i Ic],:)=U([Ic i],:);
                f=f+1;
            end
            tmp=abs(N);
            [Mf,If]=max(tmp(i,:));
            if((tmp(i,i))~=Mf)
                N(:,[i If])=N(:,[If i]);
                %tmp(:,[i If])=tmp(:,[If i]);
                f=f+1;
            end 
            if(f==2)
                 app.r.Value=strcat("Error: No se puede reordenar la matriz ",num2str(f));
                 return;
            end
          end   
          app.r.Value="Exito: Se reordeno la matriz";
          app.B.Data=U;
          app.A.Data=N;
          app.Calcular.Enable='on';
        end

        % Button pushed function: Calcular
        function CalcularButtonPushed2(app, event)
             tamanoS = cell(app.NInteraciones.Value, app.n+1);
            tamanoS(:,:) = {''};
            set(app.S, 'Data', tamanoS);
            set(app.S, 'ColumnEditable', false);
            for i=1:app.NInteraciones.Value
                app.S.RowName(i) = {strcat('i', int2str(i))};
            end   
            error=app.ErrorInicio.Value;
            error=str2double(error);
            iteraciones=app.NInteraciones.Value;
            %iteraciones=str2double(iteraciones);
            A=get(app.A,'data');
            %A=str2double(A); 
            
            b=get(app.B,'data');
            %b=str2double(b)
            %b=b';
            x=zeros(app.n,1);
           
            Jacobi(app,A,b,x,error,iteraciones);
        end

        % Button pushed function: btnRetroceder
        function btnRetrocederButtonPushed(app, event)
            FormularioPrincipal;
            delete(app)
            
        end

        % Button pushed function: btnExternLink
        function btnExternLinkButtonPushed(app, event)
            web('https://www.youtube.com/watch?v=EiwqU5WB2Wc')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.A = uitable(app.UIFigure);
            app.A.ColumnName = {'Pulse +'};
            app.A.RowName = {};
            app.mas = uibutton(app.UIFigure, 'push');
            app.mas.ButtonPushedFcn = createCallbackFcn(app, @masButtonPushed, true);
            app.mas.Text = '+';
            app.menos = uibutton(app.UIFigure, 'push');
            app.menos.ButtonPushedFcn = createCallbackFcn(app, @menosButtonPushed, true);
            app.menos.Text = '-';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '=';
            app.B = uitable(app.UIFigure);
            app.B.ColumnName = {'B'};
            app.B.RowName = {};
            app.r = uitextarea(app.UIFigure);
            app.r.Editable = 'off';
            app.ErrorInicio = uieditfield(app.UIFigure, 'numeric');
            app.ErrorInicio.Value = 0.01;
            app.IngresesusdatosAquiLabel = uilabel(app.UIFigure);
            app.IngresesusdatosAquiLabel.Text = 'Ingrese sus datos Aqui:';
            app.ErrorLabel = uilabel(app.UIFigure);
            app.ErrorLabel.Text = 'Error:';
            app.NInteraciones = uieditfield(app.UIFigure, 'numeric');
            app.NInteraciones.Value = 3;
            app.NIteracionesLabel = uilabel(app.UIFigure);
            app.NIteracionesLabel.Text = 'N° Iteraciones:';
            app.S = uitable(app.UIFigure);
            app.S.ColumnName = '';
            app.S.RowName = {};
            app.Comprobar = uibutton(app.UIFigure, 'push');
            app.Comprobar.ButtonPushedFcn = createCallbackFcn(app, @ComprobarButtonPushed2, true);
            app.Comprobar.Text = 'Comprobar';
            app.METODODEJACOBILabel = uilabel(app.UIFigure);
            app.METODODEJACOBILabel.Text = 'METODO DE JACOBI';
            app.Calcular = uibutton(app.UIFigure, 'push');
            app.Calcular.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed2, true);
            app.Calcular.Enable = 'off';
            app.Calcular.Text = 'Calcular';
            app.btnRetroceder = uibutton(app.UIFigure, 'push');
            app.btnRetroceder.ButtonPushedFcn = createCallbackFcn(app, @btnRetrocederButtonPushed, true);
            app.btnRetroceder.Icon = 'back.png';
            app.btnRetroceder.Text = '';
            app.btnExternLink = uibutton(app.UIFigure, 'push');
            app.btnExternLink.ButtonPushedFcn = createCallbackFcn(app, @btnExternLinkButtonPushed, true);
            app.btnExternLink.Icon = 'extern-link.png';
            app.btnExternLink.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetodoJacobi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end