classdef MetodoNewtonRaphson < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        RESOLVERButton        matlab.ui.control.Button
        ErrorEditFieldLabel   matlab.ui.control.Label
        ErrorEditField        matlab.ui.control.NumericEditField
        ResultadoLabel        matlab.ui.control.Label
        AgregarcolumnaButton  matlab.ui.control.Button
        QuitarcolumnaButton   matlab.ui.control.Button
        NmerodeiteracionesEditFieldLabel  matlab.ui.control.Label
        Iteraciones           matlab.ui.control.NumericEditField
        Ecuaciones            matlab.ui.control.Table
        Resultados            matlab.ui.control.Table
        NEWTONRAPHSONLabel    matlab.ui.control.Label
        EcuacionesLabel       matlab.ui.control.Label
        btnRetroceder         matlab.ui.control.Button
        btnExternLink         matlab.ui.control.Button
    end

    
    properties (Access = private)
        n=3; % Description
        iter=3; % Description
        userError % Description
    end
    
    methods (Access = private)
        
        function startMatrix(app)
            sizeA = cell(app.n-1, 2);
            sizeA(:,:) = {''};
            set(app.Ecuaciones, 'Data', sizeA);
            set(app.Ecuaciones, 'ColumnEditable', true(1, app.n));
            
            app.Ecuaciones.ColumnName(1) = {'Funcion'};
            app.Ecuaciones.ColumnName(2) = {'Resultado'};
            for i=1:app.n-1
                app.Ecuaciones.RowName(i) = {strcat('e', int2str(i))};
            end
        end
        
        function startResultMatrix(app)
            sizeA = cell(app.n-1, 1);
            sizeA(:,:) = {''};
            set(app.Resultados, 'Data', sizeA);
            set(app.Resultados, 'ColumnEditable', false);
            app.Resultados.ColumnName(1) = {'Resultado'};
            for i=1:app.iter
                app.Resultados.RowName(i) = {strcat('x', int2str(i))};
            end
        end
        
        function [sol, error] = newtonraphson(app, F, x0, maxiter)
            % Newton-Raphson solver for a system of nonlinear equations, where F is the
            % handle to the system of equations and x0 is the starting point for the
            % solver. The output of F is a column vector, x0 is a column vector.
            % Maxiter species the maximum number of iterations, default is 1000.
            % initialise values
            error1 = 1e8;
            x = x0;
            itera = 0;
            if nargin < 3
                maxiter = 1e3;
            end
            error = zeros(maxiter, 1);
            sol = F(x0); % compute test solution
            nvar = length(sol); % compute size of the system
            h = 1e-4 .* ones(nvar, 1); % size of step for derivative computation
            while error1 > app.userError
                itera = itera+1; % update iteration
                f = F(x); % evaluate function at current point
                
                J = jacobiannum(app, F, x, h); % compute the Jacobian
                y = -J\f;  % solve the linear equations
                x = x + y; % move the solution
                
                % calculate errors
                error1 = sqrt(sum(y.^2));
                error(itera) = sqrt(sum(f.^2));
                
                % break computation if maximum number of iterations is exceeded
                if itera == maxiter
                    warning('Maximum number of iterations (%i) exceeded, solver may not have converged.', maxiter);
                    break;
                end 
            end
            % return solution and error for each iteration
            sol = x;
            error = error(1:itera);
            end
            function J = jacobiannum(app, F,x,h)
            % Computes the Jacobian matrix of the function F at the point x, where h is
            % the step size to take on each dimension (has to be small enough for
            % precision). Note that the result of f and vectors x and h must be column
            % vectors of the same dimensions.
            J = (F(repmat(x,size(x'))+diag(h))-F(repmat(x,size(x'))))./h';
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startMatrix(app);
            startResultMatrix(app);
        end

        % Button pushed function: AgregarcolumnaButton
        function AgregarcolumnaButtonPushed(app, event)
            app.n = app.n+1;
            startMatrix(app);
            startResultMatrix(app);
        end

        % Button pushed function: QuitarcolumnaButton
        function QuitarcolumnaButtonPushed(app, event)
            if app.n>3
                app.n = app.n-1;
                startMatrix(app);
                startResultMatrix(app);
            end
        end

        % Value changed function: Iteraciones
        function IteracionesValueChanged(app, event)
            app.iter = app.Iteraciones.Value;
            startResultMatrix(app);
        end

        % Button pushed function: RESOLVERButton
        function RESOLVERButtonPushed(app, event)
            function_array = zeros(1, app.n-1);
            function_array = string(function_array);
            function_string_array = '@(x)[';
            matrix = get(app.Ecuaciones, 'Data');
            for i=1:app.n-1
                for j=1:app.n-1
                    new_j = num2str(j);
                    variable = strcat('x', new_j);
                    display(variable);
                    new_variable = strcat('x(', new_j, ',:)');
                    matrix{i, 1} = strrep(matrix{i, 1}, variable, new_variable);
                end
                matrix{i, 1} = strrep(matrix{i, 1}, '*', '.*');
                matrix{i, 1} = strrep(matrix{i, 1}, '^', '.^');
                matrix{i, 1} = strrep(matrix{i, 1}, '/', './');
                function_array(i) = matrix{i, 1};
                disp(function_array);
            end
            for i=1:app.n-2
                function_string_array = strcat(function_string_array, function_array(i), ';');
            end
            function_string_array = strcat(function_string_array, function_array(app.n-1), ']');
            display(function_string_array);
            handle = str2func(function_string_array);
            right_side_vector = str2double(matrix(1:app.n-1, 2));
            display(handle);
            display(right_side_vector);
            [sol, err] = newtonraphson(app, handle, right_side_vector, app.iter);
            set(app.Resultados, 'Data', sol);
        end

        % Button pushed function: btnRetroceder
        function btnRetrocederButtonPushed(app, event)
            FormularioPrincipal;
            delete(app);
        end

        % Value changed function: ErrorEditField
        function ErrorEditFieldValueChanged(app, event)
            app.userError = app.ErrorEditField.Value;
        end

        % Button pushed function: btnExternLink
        function btnExternLinkButtonPushed(app, event)
            web('https://www.youtube.com/watch?v=QS3wqOQabVY');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Método Newton Raphson';
            app.RESOLVERButton = uibutton(app.UIFigure, 'push');
            app.RESOLVERButton.ButtonPushedFcn = createCallbackFcn(app, @RESOLVERButtonPushed, true);
            app.RESOLVERButton.Text = 'Calcular';
            app.ErrorEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorEditFieldLabel.Text = 'Error';
            app.ErrorEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErrorEditField.ValueChangedFcn = createCallbackFcn(app, @ErrorEditFieldValueChanged, true);
            app.ErrorEditField.Value = 0.01;
            app.ResultadoLabel = uilabel(app.UIFigure);
            app.ResultadoLabel.Text = 'Resultado';
            app.AgregarcolumnaButton = uibutton(app.UIFigure, 'push');
            app.AgregarcolumnaButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarcolumnaButtonPushed, true);
            app.AgregarcolumnaButton.Text = 'Agregar';
            app.QuitarcolumnaButton = uibutton(app.UIFigure, 'push');
            app.QuitarcolumnaButton.ButtonPushedFcn = createCallbackFcn(app, @QuitarcolumnaButtonPushed, true);
            app.QuitarcolumnaButton.Text = 'Quitar';
            app.NmerodeiteracionesEditFieldLabel = uilabel(app.UIFigure);
            app.NmerodeiteracionesEditFieldLabel.Text = 'Número de iteraciones:';
            app.Iteraciones = uieditfield(app.UIFigure, 'numeric');
            app.Iteraciones.ValueChangedFcn = createCallbackFcn(app, @IteracionesValueChanged, true);
            app.Iteraciones.Value = 3;
            app.Ecuaciones = uitable(app.UIFigure);
            app.Ecuaciones.ColumnName = {'Funcion'; 'Inicial'};
            app.Ecuaciones.RowName = {};
            app.Resultados = uitable(app.UIFigure);
            app.Resultados.ColumnName = {''};
            app.Resultados.RowName = {};
            app.NEWTONRAPHSONLabel = uilabel(app.UIFigure);
            app.NEWTONRAPHSONLabel.Text = 'NEWTON RAPHSON';
            app.EcuacionesLabel = uilabel(app.UIFigure);
            app.EcuacionesLabel.Text = 'Ecuaciones';
            app.btnRetroceder = uibutton(app.UIFigure, 'push');
            app.btnRetroceder.ButtonPushedFcn = createCallbackFcn(app, @btnRetrocederButtonPushed, true);
            app.btnRetroceder.Icon = 'back.png';
            app.btnRetroceder.Text = '';
            app.btnExternLink = uibutton(app.UIFigure, 'push');
            app.btnExternLink.ButtonPushedFcn = createCallbackFcn(app, @btnExternLinkButtonPushed, true);
            app.btnExternLink.Icon = 'extern-link.png';
            app.btnExternLink.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetodoNewtonRaphson

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end